classdef HueDashboard < matlab.apps.AppBase
    %HUEDASHBOARD (Developer and support notes)
    %   Developed by:
    %   Stephen Folmer
    %   spfolmer@niceway.com
    %   (c) 2025 Stephen Folmer
    %   
    %   Provided 'as is' without support or warranty.
    %   
    %   ----------------------------------------------------------------
    %   Developed on Matlab r2025 but, save for dark mode presentation,
    %   will be backwards compatible for several releases.
    %   ----------------------------------------------------------------
    %   
    %   Update log:
    %   20251015 - (ra4) v1.4 - corrected handling for change in Bridge IP
    %   - (ra5) v1.5 - tidied up 'no internet' handling.
    %   20251017 - (ra6) v1.6 - added detailed help text for control of
    %   lights.  
    %   20251019 - v2.0 - complete re-design of the Bridge pairing
    %   (adding to whitelist) and IP address update processes.
    %   20251022 - v2.1 cosmetic changes to informative messages.
    %   20251101 - v2.2 slightly larger display area, with new colouration,
    %   some larger fonts and real-time lights' status count after
    %   toggling.
    %   20251104 - v2.3 included dimmer switches in the sensor data table.
    %   dynamic text for low battery and unreachable lights diagnostics.
    %   20251105 - v2.4 added Last Event column to Sensors and Switches
    %   grid.  Moved things around to accommodate the expanded grid.  Also
    %   various display and performance enhancements..
    %   20251109 - v2.5 improvements to Rules details - cater for 'zero'
    %   groups and dimmers quirks.  Added 'last motion detected' section.
    %   20251112 - v2.6 added max/min temperatures in Sensor Temperatures
    %   panel title.
    %   20251121 - v2.7 added dynamic text indicating change in maximum and
    %   minimum temperatures.

    % Properties that correspond to app components
    properties (Access = public)
        PhilipsHueDashboardv27UIFigure  matlab.ui.Figure
        WarningsPanel                   matlab.ui.container.Panel
        LBSensors                       matlab.ui.control.NumericEditField
        LowBatteryDevicesLabel          matlab.ui.control.Label
        URLights                        matlab.ui.control.NumericEditField
        UnreachableLightsLabel_2        matlab.ui.control.Label
        LastMotionDetected              matlab.ui.control.Label
        onLabel                         matlab.ui.control.Label
        LabelLightsOn                   matlab.ui.control.Label
        BridgeConfigurationandUsagePanel  matlab.ui.container.Panel
        InLabel                         matlab.ui.control.Label
        TotalDevices                    matlab.ui.control.Label
        devicesLabel                    matlab.ui.control.Label
        SwitchesLabel                   matlab.ui.control.Label
        DimmerSwitches                  matlab.ui.control.Label
        MotionSensorsLabel              matlab.ui.control.Label
        MotionSensors                   matlab.ui.control.Label
        PCSensors                       matlab.ui.control.NumericEditField
        BRSensors                       matlab.ui.control.NumericEditField
        AVSensors                       matlab.ui.control.NumericEditField
        SensorsLabel                    matlab.ui.control.Label
        PCResourceLinks                 matlab.ui.control.NumericEditField
        BRResourceLinks                 matlab.ui.control.NumericEditField
        AVResourceLinks                 matlab.ui.control.NumericEditField
        ResourceLinksLabel              matlab.ui.control.Label
        ONSchedules                     matlab.ui.control.NumericEditField
        PCSchedules                     matlab.ui.control.NumericEditField
        BRSchedules                     matlab.ui.control.NumericEditField
        AVSchedules                     matlab.ui.control.NumericEditField
        SchedulesLabel                  matlab.ui.control.Label
        PCScenes                        matlab.ui.control.NumericEditField
        BRScenes                        matlab.ui.control.NumericEditField
        AVScenes                        matlab.ui.control.NumericEditField
        ScenesLabel                     matlab.ui.control.Label
        ONGroups                        matlab.ui.control.NumericEditField
        PCGroups                        matlab.ui.control.NumericEditField
        BRGroups                        matlab.ui.control.NumericEditField
        AVGroups                        matlab.ui.control.NumericEditField
        GroupsLabel                     matlab.ui.control.Label
        ONRules                         matlab.ui.control.NumericEditField
        PCRules                         matlab.ui.control.NumericEditField
        BRRules                         matlab.ui.control.NumericEditField
        AVRules                         matlab.ui.control.NumericEditField
        RulesLabel                      matlab.ui.control.Label
        ONLights                        matlab.ui.control.NumericEditField
        PCLights                        matlab.ui.control.NumericEditField
        BRLights                        matlab.ui.control.NumericEditField
        AVLights                        matlab.ui.control.NumericEditField
        LightsLabel                     matlab.ui.control.Label
        OnActiveLabel                   matlab.ui.control.Label
        UsedLabel                       matlab.ui.control.Label
        BridgeLimitLabel                matlab.ui.control.Label
        InstalledLabel                  matlab.ui.control.Label
        DiagnosticsTimeTextArea         matlab.ui.control.TextArea
        DiagnosticsMsgTextArea          matlab.ui.control.TextArea
        DiagnosticsTextAreaLabel        matlab.ui.control.Label
        TableSensors                    matlab.ui.control.Table
        DeviceswithBatteryLevelbelow40Label  matlab.ui.control.Label
        TableLights                     matlab.ui.control.Table
        UnreachableLightsLabel          matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        inTimesTriggeredOrderLabel      matlab.ui.control.Label
        RulesTable                      matlab.ui.control.Table
        NeverTriggered                  matlab.ui.control.NumericEditField
        NumberofRulesNOTtriggeredsincethelastBridgefirmwareupdateLabel  matlab.ui.control.Label
        RulesLabel_2                    matlab.ui.control.Label
        LightsTable                     matlab.ui.control.Table
        SelectcellstotoggleonoffOReditBriColforbrightnessColourLabel  matlab.ui.control.Label
        AllLightsOn                     matlab.ui.control.Button
        AllLightsOff                    matlab.ui.control.Button
        ToggleALLLightsButton           matlab.ui.control.Button
        LightsLabel_2                   matlab.ui.control.Label
        TempsTable                      matlab.ui.control.Table
        SensorsandSwitchesLabel         matlab.ui.control.Label
        SensorTemperaturesPanel         matlab.ui.container.Panel
        FRSensors                       matlab.ui.control.NumericEditField
        FreezingLable                   matlab.ui.control.Label
        ColdLable                       matlab.ui.control.Label
        CoolLabel                       matlab.ui.control.Label
        WarmLabel                       matlab.ui.control.Label
        HotLabel                        matlab.ui.control.Label
        HOSensors                       matlab.ui.control.NumericEditField
        WASensors                       matlab.ui.control.NumericEditField
        COSensors                       matlab.ui.control.NumericEditField
        CLSensors                       matlab.ui.control.NumericEditField
        CMSensors                       matlab.ui.control.NumericEditField
        CMSensorsLabel                  matlab.ui.control.Label
        SWUpDateAvail                   matlab.ui.control.TextArea
        Label                           matlab.ui.control.Label
        SWversion                       matlab.ui.control.TextArea
        BridgeID                        matlab.ui.control.TextArea
        IPAddressLabel_2                matlab.ui.control.Label
        Response                        matlab.ui.control.NumericEditField
        LastBridgeDataRefreshTextArea   matlab.ui.control.TextArea
        AutoSWUpdate                    matlab.ui.control.TextArea
        LastFirmwareUpdate              matlab.ui.control.TextArea
        ZigbeeChannel                   matlab.ui.control.NumericEditField
        IPAddress                       matlab.ui.control.TextArea
        APIversion                      matlab.ui.control.TextArea
        ZigbeeChannelLabel_2            matlab.ui.control.Label
        PreviousStatusRefreshLabel      matlab.ui.control.Label
        IPAddressLabel_4                matlab.ui.control.Label
        LastFirmwareUpdateSoftwareVersionTextAreaLabel  matlab.ui.control.Label
        ZigbeeChannelLabel              matlab.ui.control.Label
        IPAddressLabel                  matlab.ui.control.Label
        IPAddressLabel_3                matlab.ui.control.Label
        RefreshButton                   matlab.ui.control.Button
        PhilipsHueBridgeDashboardLabel  matlab.ui.control.Label
        ExitButton                      matlab.ui.control.Button
        ContextMenu                     matlab.ui.container.ContextMenu
        Menu                            matlab.ui.container.Menu
    end


    properties (Access = private)
        fName
        hueIPAddress
        appID
        dataRepository
        newBri
        cellSelIndices
        prevCellSel
        prevEventName
        editGroup
        lastKey
        xyColour
        newColr
        successfulStart
        prevMaxTemp
        prevMinTemp
    end

    methods (Access = private)

        function errCode = getStats(app, request)

            tic

            addpath(genpath(app.dataRepository));
            errCode = 0;

            switch request
                case 'refresh'
                    [errCode, app.hueIPAddress, app.appID, allLights, allRules, allSensors, allGroups, ...
                        allSchedules, allCapabilities, allResourceLinks, allScenes, config] = connectToBridge(app);

                    switch errCode
                        case 777
                            dt = datetime('now', 'Format', 'dd/MM/yy HH:mm:ss');
                            strDt = string(dt);
                            app.DiagnosticsTimeTextArea.Value = strDt;
                            app.DiagnosticsMsgTextArea.Value = '** Unable to look up the curent Hue IP address using https://discovery.meethue.com... (If using a VPN turn it off for the duration of this process **';
                            return;
                        case 888
                            dt = datetime('now', 'Format', 'dd/MM/yy HH:mm:ss');
                            strDt = string(dt);
                            app.DiagnosticsTimeTextArea.Value = strDt;
                            app.DiagnosticsMsgTextArea.Value = '** Not connected to Bridge **';
                            return;
                        case 999
                            dt = datetime('now', 'Format', 'dd/MM/yy HH:mm:ss');
                            strDt = string(dt);
                            app.DiagnosticsTimeTextArea.Value = strDt;
                            app.DiagnosticsMsgTextArea.Value = '** No Internet **';
                            return;
                        otherwise
                    end

                case 'startup'
                    app.prevMaxTemp = -999;
                    app.prevMinTemp = 999;
                    if ispc
                        app.fName = [app.dataRepository '\userNameHue.mat'];
                    else
                        app.fName = [app.dataRepository '/userNameHue.mat'];
                    end
                    try
                        fprintf('Checking that %s is readable...', app.fName);
                        load(app.fName);  % userNameHue
                        app.appID = hueUser;
                        disp(app.appID)
                        fprintf('OK. \n');
                    catch EM
                        disp(EM.message)
                        disp(EM.cause);
                        fprintf('PROBLEM...Creating temporary dummy ID...will need to pair with Bridge.\n');
                        hueUser = 'dummy-need-to-pair';
                        save([app.fName],"hueUser");
                    end
                    return;
                otherwise
            end

            app.cellSelIndices = [0 0];
            app.newBri = 0;
            app.prevEventName = 'none';
            app.editGroup = '';
            cd (app.dataRepository)

            app.IPAddress.Value = config.ipaddress;
            app.hueIPAddress = config.ipaddress;
            app.BridgeID.Value = config.bridgeid;
            app.ZigbeeChannel.Value = config.zigbeechannel;
            app.APIversion.Value = config.apiversion;
            if config.swupdate2.autoinstall.on
                app.AutoSWUpdate.Value = 'On';
            else
                app.AutoSWUpdate.Value = 'Off';
            end
            % Convert to datetime using input format
            dt = datetime(config.swupdate2.bridge.lastinstall, 'InputFormat', 'yyyy-MM-dd''T''HH:mm:ss');
            dt.Format = 'dd-MMM-yyyy HH:mm:ss';
            app.LastFirmwareUpdate.Value = string(dt);
            app.SWversion.Value = config.swversion;
            app.SWUpDateAvail.Value = config.swupdate2.bridge.state;
            if config.swupdate2.bridge.state == "noupdates"
                app.SWUpDateAvail.Value = 'No updates available';
            else
                if contains(config.swupdate2.bridge.state,'readytoinstall')
                    app.SWUpDateAvail.Value = 'Updates available' ;
                else
                    app.SWUpDateAvail.Value = 'Unknown';
                end
            end

            app.AVLights.Value = allCapabilities.lights.total - allCapabilities.lights.available;
            app.BRLights.Value = allCapabilities.lights.total;
            app.PCLights.Value = (allCapabilities.lights.total - allCapabilities.lights.available) ...
                * 100 / allCapabilities.lights.total;
            app.AVSensors.Value = allCapabilities.sensors.total - allCapabilities.sensors.available;
            app.BRSensors.Value = allCapabilities.sensors.total;
            app.PCSensors.Value = (allCapabilities.sensors.total - allCapabilities.sensors.available) ...
                * 100 / allCapabilities.sensors.total;
            app.AVGroups.Value = allCapabilities.groups.total - allCapabilities.groups.available;
            app.BRGroups.Value = allCapabilities.groups.total;
            app.PCGroups.Value = (allCapabilities.groups.total - allCapabilities.groups.available) ...
                * 100 / allCapabilities.groups.total;
            app.AVScenes.Value = allCapabilities.scenes.total - allCapabilities.scenes.available;
            app.BRScenes.Value = allCapabilities.scenes.total;
            app.PCScenes.Value = (allCapabilities.scenes.total - allCapabilities.scenes.available) ...
                * 100 / allCapabilities.scenes.total;
            app.AVSchedules.Value = allCapabilities.schedules.total - allCapabilities.schedules.available;
            app.BRSchedules.Value = allCapabilities.schedules.total;
            app.PCSchedules.Value = (allCapabilities.schedules.total - allCapabilities.schedules.available) ...
                * 100 / allCapabilities.schedules.total;
            app.AVRules.Value = allCapabilities.rules.total - allCapabilities.rules.available;
            app.BRRules.Value = allCapabilities.rules.total;
            app.PCRules.Value = (allCapabilities.rules.total - allCapabilities.rules.available) ...
                * 100 / allCapabilities.rules.total;
            app.AVResourceLinks.Value = allCapabilities.resourcelinks.total - allCapabilities.resourcelinks.available;
            app.BRResourceLinks.Value = allCapabilities.resourcelinks.total;
            app.PCResourceLinks.Value = (allCapabilities.resourcelinks.total - allCapabilities.resourcelinks.available) ...
                * 100 / allCapabilities.resourcelinks.total;

            %%%%
            %%%%  Sensors
            %%%%

            tableSensors = struct2table(allSensors);
            noSensors = size(tableSensors);
            fieldX = fieldnames(allSensors(1,1));
            % Create a standard format table of Sensors
            sz = [noSensors(1,2) 9];
            varTypes = ["double", "string", "string", "string", "string", "double", "double","datetime","string"];
            varNames = ["sensorno", "sensorname", "modelID", "productname", "type", "battery", "temp","lastevent","uniqueid"];
            tSensors = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);
            tSensors.sensorno = str2double(extractAfter(fieldX, 'x'));
            lowBattery = 0;
            maxTemp = -99;
            maxRoom = [];
            minTemp = 99;
            minRoom = [];
            warm = 0;
            veryWarm = 0;
            cool = 0;
            cold = 0;
            freezing = 0;
            tgt = 0;
            noTemps = 0;
            noMotion = 0;
            noDimmers = 0;
            for i = 1:noSensors(1,2)
                try
                    battery = tableSensors.(fieldX{i,1}).config.battery;
                    tSensors.battery(i) = tableSensors.(fieldX{i,1}).config.battery;
                    tSensors.productname(i) = tableSensors.(fieldX{i,1}).productname;
                    tSensors.modelID(i) = tableSensors.(fieldX{i,1}).modelid;
                    tSensors.uniqueid(i) = tableSensors.(fieldX{i,1}).uniqueid;
                    try
                        tSensors.lastevent(i) = tableSensors.(fieldX{i,1}).state.lastupdated;
                    catch
                        tSensors.lastevent(i) = tableSensors.(fieldX{i,1}).swupdate.lastinstall;
                    end
                    if contains(tSensors.productname(i),'motion')
                        noMotion = noMotion + 1;
                        if battery < 40
                            lowBattery = lowBattery + 1;
                        end
                    end
                    if contains(tSensors.productname(i),'dimmer')
                        noDimmers = noDimmers + 1;
                        if battery < 40
                            lowBattery = lowBattery + 1;
                        end
                    end
                catch
                end
                tSensors.sensorname(i) = tableSensors.(fieldX{i,1}).name;
                tSensors.type(i) = tableSensors.(fieldX{i,1}).type;
                if  contains(tSensors.type(i) ,"Temperature")
                    tSensors.temp(i) = tableSensors.(fieldX{i,1}).state.temperature/100;
                    noTemps = noTemps + 1;
                    if tSensors.temp(i) > maxTemp
                        maxTemp = tSensors.temp(i);
                        maxRoom = extractBetween(tSensors.sensorname(i),'Sensor','Temp');
                    end
                    if tSensors.temp(i) < minTemp
                        minTemp = tSensors.temp(i);
                        minRoom = extractBetween(tSensors.sensorname(i),'Sensor','Temp');
                    end
                    if tSensors.temp(i) > 30
                        veryWarm = veryWarm + 1;
                    elseif tSensors.temp(i) > 21
                        warm = warm + 1;
                    elseif tSensors.temp(i) < 0
                        freezing = freezing + 1;
                    elseif tSensors.temp(i) < 10
                        cold = cold + 1;
                    elseif tSensors.temp(i) < 18
                        cool = cool + 1;
                    else
                        tgt = tgt + 1;
                    end
                end
            end
            app.LBSensors.Value = lowBattery;
            app.WASensors.Value = warm;
            app.HOSensors.Value = veryWarm;
            app.CLSensors.Value = cool;
            app.COSensors.Value = cold;
            app.FRSensors.Value = freezing;
            app.CMSensors.Value = tgt;
            if app.prevMaxTemp == -999
                app.prevMaxTemp = maxTemp;
            end
            if app.prevMinTemp == 999
                app.prevMinTemp = minTemp;
            end
            if maxTemp > app.prevMaxTemp
                maxTempText = append('<font color="yellow">Max: </font>', maxRoom,...
                    '<font color="yellow">',' RISING',' </font>', ' to ', num2str(maxTemp,'%+2.2f'));
                app.prevMaxTemp = maxTemp;
            elseif maxTemp < app.prevMaxTemp
                maxTempText = append('<font color="yellow">Max: </font>', maxRoom, ...
                    '<font color="cyan">',' DROPPING',' </font>', ' to ', num2str(maxTemp,'%+2.2f'));
                app.prevMaxTemp = maxTemp;
            else
                maxTempText = append('<font color="yellow">Max: </font>', maxRoom, ...
                    '<font color="grey">',' STABLE',' </font>', ' at ', num2str(maxTemp,'%+2.2f'));
            end

            if minTemp > app.prevMinTemp
                minTempText = append('<font color="yellow">Min: </font>', minRoom, ...
                    '<font color="yellow">',' RISING',' </font>', ' to ', num2str(minTemp,'%+2.2f'));
                app.prevMinTemp = minTemp;
            elseif minTemp < app.prevMinTemp
                minTempText = append('<font color="yellow">Min: </font>', minRoom, ...
                    '<font color="cyan">',' DROPPING',' </font>', ' to ', num2str(minTemp,'%+2.2f'));
                app.prevMinTemp = minTemp;
            else
                minTempText = append('<font color="yellow">Min: </font>', minRoom, ...
                    '<font color="grey">',' STABLE',' </font>', ' at ', num2str(minTemp,'%+2.2f'));
            end

            app.SensorTemperaturesPanel.Title = append('Sensor Temperatures           Max ',...
                num2str(maxTemp,'%+2.2f'), ' <-> Min ', num2str(minTemp,'%+2.2f'));
            app.MotionSensors.Text = num2str(noMotion);
            app.DimmerSwitches.Text = num2str(noDimmers);
            app.TotalDevices.Text =num2str(noMotion + noDimmers);
            noTempsnDimmers = noTemps + noDimmers;
            sz = [noTempsnDimmers 4];
            varTypes = ["string", "double", "string","datetime"];
            varNames = ["temp", "battery", "sensorname","lastevent"];
            tTemps = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);
            j = 0;
            lastMotionIdx = 0;
            for i = 1:noSensors(1,2)
                if  contains(tSensors.type(i) ,"Temperature")
                    j = j + 1;
                    tTemps.temp(j) = num2str(tSensors.temp(i),'%+2.2f');
                    tTemps.battery(j) = tSensors.battery(i);
                    tTemps.sensorname(j) = strcat(tSensors.sensorname(i), ' (', tSensors.modelID(i), ')');
                    tTemps.lastevent(j) = tSensors.lastevent(i);
                elseif contains(tSensors.type(i) ,"Switch")
                    j = j+ 1;
                    tTemps.temp(j) = ' ';
                    tTemps.battery(j) = tSensors.battery(i);
                    tTemps.sensorname(j) = strcat(tSensors.sensorname(i), ' (', tSensors.modelID(i), ')');
                    tTemps.lastevent(j) = tSensors.lastevent(i);
                elseif contains(tSensors.type(i) ,"Presence")
                    if lastMotionIdx == 0 || tSensors.lastevent(i) > lastMotion
                        lastMotion = tSensors.lastevent(i);
                        lastMotionIdx = i;
                    end
                end
            end

            if lastMotionIdx > 0
                motionDate = extractBefore(string(lastMotion), " ");
                motionTime = extractAfter(string(lastMotion), " ");
                app.LastMotionDetected.Text = append('Last Motion detected by ', tSensors.sensorname(lastMotionIdx),...
                    newline, ' on ', motionDate, ' at ', motionTime);
                if strlength(tSensors.sensorname(lastMotionIdx)) > 25
                    app.LastMotionDetected.FontSize = 16;
                else
                    app.LastMotionDetected.FontSize = 18;
                end
                app.LastMotionDetected.FontColor = 'cyan';
                app.LastMotionDetected.FontWeight = 'bold';
            else
                app.LastMotionDetected.Text = 'No Motions detected.';
                app.LastMotionDetected.FontSize = 14;
                app.LastMotionDetected.FontColor = 'yellow';
                app.LastMotionDetected.FontWeight = 'normal';
            end

            tTempsSorted = sortrows(tTemps,{'temp','sensorname'},{'descend', 'ascend'});
            tTemps = tTempsSorted;
            app.TempsTable.Data = tTemps;

            if lowBattery > 0
                sz = [lowBattery 3];
                varTypes = ["double", "string", "string"];
                varNames = ["battery", "modelID", "sensorname"];
                tLowBat = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);
                j = 0;
                for i = 1:noSensors(1,2)
                    try
                        battery = tableSensors.(fieldX{i,1}).config.battery;
                        if (contains(tSensors.productname(i),'motion') ...
                                || contains(tSensors.productname(i),'dimmer')) ...
                                && battery < 40
                            j = j + 1;
                            tLowBat.battery(j) = battery;
                            tLowBat.modelID(j) = tableSensors.(fieldX{i,1}).modelid;
                            tLowBat.sensorname(j) = tableSensors.(fieldX{i,1}).name;
                        end
                    catch
                    end
                end
                tLBSorted = sortrows(tLowBat);
                app.TableSensors.Data = tLBSorted;
                s1 = uistyle('BackgroundColor','red');
                s2 = uistyle;
                s2.FontColor = 'black';
                addStyle(app.TableSensors,s1,'column',1);
                addStyle(app.TableSensors,s2,'column',1);
                app.TableSensors.Visible = "on";
                app.DeviceswithBatteryLevelbelow40Label.Text = 'Devices with a Battery Level below 40%';
                app.DeviceswithBatteryLevelbelow40Label.FontColor = "red";
                app.DeviceswithBatteryLevelbelow40Label.FontWeight = "bold";
            else
                app.TableSensors.Visible = "off";
                app.DeviceswithBatteryLevelbelow40Label.Text = 'All devices have a Battery Level above 40%';
                app.DeviceswithBatteryLevelbelow40Label.FontColor = "green";
                app.DeviceswithBatteryLevelbelow40Label.FontWeight = "bold";
            end

            %%%%
            %%%% Lights
            %%%%

            tableLights = struct2table(allLights);
            noLights = size(tableLights);
            fieldX = fieldnames(allLights(1,1));

            % Create a standard format table of Lights
            sz = [noLights(1,2) 9];
            varTypes = ["double", "string", "string", "string","string","double","logical","string","logical"];
            varNames = ["lightno", "lightname", "productname", "model", "type","bright","on","uniqueid","reachable"];
            tLights = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);
            tLights.lightno = str2double(extractAfter(fieldX, 'x'));
            lightsReachable = 0;
            lightsOn = 0;

            for i = 1:noLights(1,2)
                tLights.model(i) = tableLights.(fieldX{i,1}).modelid;
                tLights.productname(i) = tableLights.(fieldX{i,1}).productname;
                tLights.lightname(i) = tableLights.(fieldX{i,1}).name;
                tLights.type(i) = tableLights.(fieldX{i,1}).type;
                tLights.reachable(i) = tableLights.(fieldX{i,1}).state.reachable;
                tLights.uniqueid(i) = tableLights.(fieldX{i,1}).uniqueid;
                tLights.bright(i) = tableLights.(fieldX{i,1}).state.bri;
                if tLights.reachable(i)
                    lightsReachable = lightsReachable + 1;
                end
                tLights.on(i) = tableLights.(fieldX{i,1}).state.on;
                if tLights.on(i)
                    lightsOn = lightsOn + 1;
                end
            end

            unreachable = noLights(1,2) - lightsReachable;
            app.URLights.Value = unreachable;
            app.ONLights.Value = lightsOn;
            app.LabelLightsOn.Text = num2str(lightsOn);

            if unreachable > 0
                sz = [unreachable 2];
                varTypes = ["string", "string"];
                varNames = ["ID", "lightname"];
                tUnreachable = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);
                j = 0;
                for i = 1:noLights(1,2)
                    if ~ tLights.reachable(i)
                        j = j + 1;
                        tUnreachable.ID(j) = tLights.model(i);
                        tUnreachable.lightname(j) =  tLights.lightname(i);
                    end
                end

                app.TableLights.Data = tUnreachable;
                s1 = uistyle('BackgroundColor','white');
                s2 = uistyle;
                s2.FontColor = 'black';
                s2.FontWeight = 'bold';
                addStyle(app.TableLights,s1,'column',1);
                addStyle(app.TableLights,s2,'column',1);
                app.TableLights.Visible = "on";
                app.UnreachableLightsLabel.Interpreter = 'none';
                app.UnreachableLightsLabel.HorizontalAlignment = 'left';
                app.UnreachableLightsLabel.Text = 'Unreachable Lights';
                app.UnreachableLightsLabel.Tooltip =  'This section is only presented when there are Unreachable Lights.';
                app.UnreachableLightsLabel.FontColor = "red";
                app.UnreachableLightsLabel.FontWeight = "bold";
            else
                app.TableLights.Visible = "off";
                app.UnreachableLightsLabel.FontColor = "white";
                app.UnreachableLightsLabel.Tooltip = 'This section is only presented when there are no Unreachable Lights.';
                app.UnreachableLightsLabel.BackgroundColor = "none";
                app.UnreachableLightsLabel.FontWeight = "bold";
                app.UnreachableLightsLabel.FontSize = 16;
                app.UnreachableLightsLabel.HorizontalAlignment = 'left';
                app.UnreachableLightsLabel.Interpreter = 'html';
                app.UnreachableLightsLabel.Text  = ...
                    append('<html><p><font color="white"><b>', ...
                    'Temperature trends since last Refresh',...
                    '<br><br>',...
                    maxTempText, '<br><br>', ...
                    minTempText, '</b></font></p>');
            end

            %%%%
            %%%% Schedules
            %%%%

            tableSchedules = struct2table(allSchedules);
            noSchedules = size(tableSchedules);
            fieldX = fieldnames(allSchedules(1,1));

            % Create a standard format table of Schedules
            sz = [noSchedules(1,2) 6];
            varTypes = ["double", "string", "string","string","datetime","datetime"];
            varNames = ["scheduleno", "schedulename", "desc", "status","created","starttime"];
            tSchedules = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);
            tSchedules.scheduleno = str2double(extractAfter(fieldX, 'x'));
            enabledSchedules = 0;
            for i = 1:noSchedules(1,2)
                tSchedules.desc(i) = tableSchedules.(fieldX{i,1}).description;
                status = tableSchedules.(fieldX{i,1}).status;
                if status == "enabled"
                    enabledSchedules = enabledSchedules + 1;
                end
                tSchedules.status(i) = tableSchedules.(fieldX{i,1}).status;
                tSchedules.schedulename(i) = tableSchedules.(fieldX{i,1}).name;
                tSchedules.created(i) = tableSchedules.(fieldX{i,1}).created;
                try
                    tSchedules.starttime(i) = tableSchedules.(fieldX{i,1}).starttime;
                catch
                end
            end
            app.ONSchedules.Value = enabledSchedules;

            %%%%
            %%%% Groups
            %%%%

            tableGroups = struct2table(allGroups);
            noGroups = size(tableGroups);
            fieldX = fieldnames(allGroups(1,1));

            % Create a standard format table of Groups
            sz = [noGroups(1,2) 8];
            varTypes = ["double", "string", "string", "double", "string","string","logical","logical"];
            varNames = ["groupno", "groupname", "type", "nolights","lights","class","allon","anyon"];
            tGroups = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);
            tGroups.groupno = str2double(extractAfter(fieldX, 'x'));
            noGroupLights = 0;
            lightsOn = 0;
            for i = 1:noGroups(1,2)
                try
                    tGroups.lights(i) = strjoin(tableGroups.(fieldX{i,1}).lights, ', ');
                    x = size(tableGroups.(fieldX{i,1}).lights);
                    tGroups.nolights(i) = x(1,1);
                    noGroupLights = noGroupLights + x(1,1);
                catch
                    tGroups.lights(i) = "none";
                end
                tGroups.type(i) = tableGroups.(fieldX{i,1}).type;
                tGroups.groupname(i) = tableGroups.(fieldX{i,1}).name;
                tGroups.class(i) = tableGroups.(fieldX{i,1}).class;
                tGroups.allon(i) = tableGroups.(fieldX{i,1}).state.all_on;
                tGroups.anyon(i) = tableGroups.(fieldX{i,1}).state.any_on;
                if tGroups.anyon(i)
                    lightsOn = lightsOn + 1;
                end

            end

            % Create an intersection table of Groups and Lights
            sz = [noGroupLights 4];
            varTypes = ["double", "string","string","double"];
            varNames = ["groupno", "groupname", "grouptype", "lightno"];
            itGroupslights = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);
            l = 0;
            for i = 1:noGroups(1,2)
                if tGroups.nolights(i,1) > 0
                    for j = 1:tGroups.nolights(i)
                        l = l + 1;
                        itGroupslights.groupno(l) = tGroups.groupno(i,1);
                        itGroupslights.groupname(l) = tGroups.groupname(i,1);
                        itGroupslights.grouptype(l) = tGroups.type(i,1);
                        itGroupslights.lightno(l) =  str2double(tableGroups.(fieldX{i,1}).lights{j,1});
                    end
                end
            end

            tGroupsLights = join(itGroupslights, tLights);

            app.ONGroups.Value = lightsOn;

            tGLSorted = sortrows(tGroupsLights,[10 11 2],["descend" "descend" "ascend"]);
            tGroupsLights = tGLSorted;

            sz = [noGroupLights 8];
            varTypes = ["string", "string", "double","string","string","string","string","logical"];
            varNames = ["groupname", "grouptype", "lightno","lightname","productname","model","bright","state"];
            allLightsTable = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);
            allLightsTable.groupname = tGroupsLights.groupname;
            allLightsTable.grouptype = tGroupsLights.grouptype;
            allLightsTable.lightno = tGroupsLights.lightno;
            allLightsTable.lightname = tGroupsLights.lightname;
            allLightsTable.productname = tGroupsLights.productname;
            allLightsTable.model = tGroupsLights.model;
            allLightsTable.state = tGroupsLights.on;
            allLightsTable.bright = round(tGroupsLights.bright/2.54,0);
            app.LightsTable.Data = allLightsTable;
            highlightOnLights(app);

            %%%%
            %%%% Scenes
            %%%%

            tableScenes = struct2table(allScenes);
            noScenes = size(tableScenes);
            fieldX = fieldnames(allScenes(1,1));

            % Create a standard format table of Scenes
            sz = [noScenes(1,2) 6];
            varTypes = ["string", "string", "string", "datetime", "string","string"];
            varNames = ["sceneID", "scenename", "type", "lastupdate", "group", "lights"];
            tScenes = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);
            tScenes.sceneID = replace(fieldX, '_', '-');

            for i = 1:noScenes(1,2)
                tScenes.scenename(i) = tableScenes.(fieldX{i,1}).name;
                tScenes.type(i) = tableScenes.(fieldX{i,1}).type;
                tScenes.lastupdate(i) = tableScenes.(fieldX{i,1}).lastupdated;
                try
                    tScenes.group(i) = tableScenes.(fieldX{i,1}).group;
                catch
                    tScenes.group(i) = 'n/a';
                end
                try
                    tScenes.lights(i) = strjoin(tableScenes.(fieldX{i,1}).lights, ', ');
                catch
                    tScenes.lights(i) = 'none';
                end
            end

            %%%%
            %%%% Rules
            %%%%

            tableRules = struct2table(allRules);
            noRules = size(tableRules);

            fieldX = fieldnames(allRules(1,1));

            % Create a standard format table of Rules
            sz = [noRules(1,2) 18];
            varTypes = ["double", "string", "datetime", "datetime", "double", "string","string", ...
                "string", "double", "string", "string", ...
                "double", "string", ...
                "double", "string", "double", "string", "string"];
            varNames = ["ruleno", "rulename", "created", "lasttriggered", "timestriggered", "status", "action", ...
                "target", "id", "description", "RL", ...
                "groupno", "groupdesc", ...
                "sensorno", "sensordesc","scheduleno", "scheduledesc","scenedesc"];
            tRules = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);
            tRules.ruleno = str2double(extractAfter(fieldX, 'x'));
            rulesEnabled = 0;
            rulesNotTrig = 0;

            for i = 1:noRules(1,2)
                tRules.created(i) = tableRules.(fieldX{i,1}).created;
                if tableRules.(fieldX{i,1}).lasttriggered ~= "none"
                    tRules.lasttriggered(i) = tableRules.(fieldX{i,1}).lasttriggered;
                else
                    tRules.lasttriggered(i) = tableRules.(fieldX{i,1}).created; % default to date created
                end
                tRules.timestriggered(i) = tableRules.(fieldX{i,1}).timestriggered;
                if tRules.timestriggered(i) == 0
                    rulesNotTrig = rulesNotTrig + 1;
                end
                status = tableRules.(fieldX{i,1}).status;
                if status == "enabled"
                    rulesEnabled = rulesEnabled + 1;
                end
                tRules.status(i) = tableRules.(fieldX{i,1}).status;
                tRules.action(i) = tableRules.(fieldX{i,1}).actions.address;
                tRules.rulename(i) = tableRules.(fieldX{i,1}).name;
                tRules.groupno(i)  = 0;
                tRules.sensorno(i)  = 0;
                tRules.scheduleno(i) = 0;

                grF = count(tRules.action(i),"groups/");
                grFx = count(tRules.rulename(i),"Group");
                scF = count(tRules.action(i),"scenes/");
                seF = count(tRules.action(i),"sensors/");
                seFx = count(tRules.rulename(i),"Sensor");
                diF = count(tRules.rulename(i),"Dimmer");
                shF = count(tRules.action(i),"schedules/");
                xfdF = count(tRules.rulename(i),"XFD");

                %
                % Default in case no better Description extracted in following section
                %
                if seF > 0
                    tRules.sensorno(i)  = extractBetween(tRules.action(i),"sensors/","/");
                end

                if grF > 0
                    tRules.groupno(i) = extractBetween(tRules.action(i),"groups/","/action");
                    if tRules.groupno(i) == 0
                        if seFx > 0
                            tRules.sensorno(i)  = extractBetween(tRules.rulename(i),"Sensor ",".");
                        elseif diF > 0
                            tRules.sensorno(i)  = extractBetween(tRules.rulename(i),"Dimmer","Btn");
                        end
                    end
                elseif grFx > 0
                    tRules.groupno(i) = extractAfter(tRules.rulename(i),"Group");
                elseif scF > 0
                    if seFx > 0
                        tRules.sensorno(i)  = extractBetween(tRules.rulename(i),"Sensor ",".");
                    end
                elseif seF > 0
                    if seFx > 0
                        tRules.sensorno(i)  = extractBetween(tRules.rulename(i),"Sensor ",".");
                    elseif diF > 0
                        if contains(tRules.rulename(i),"Switch")
                            tRules.sensorno(i)  = extractBetween(tRules.rulename(i),"Switch "," ");
                        else
                            tRules.sensorno(i)  = extractBetween(tRules.rulename(i),"Dimmer ",".");
                        end
                    elseif xfdF > 0
                        if contains(tRules.rulename(i),"XFDMotion")
                            try
                                tRules.sensorno(i)  = extractBetween(tRules.rulename(i),"XFDMotion",".");
                            catch
                                tRules.sensorno(i)  = extractAfter(tRules.rulename(i),"XFDMotion");
                            end
                        end
                    end
                elseif shF > 0
                    tRules.scheduleno(i)  = extractAfter(tRules.action(i),"schedules/");
                    if contains(tRules.rulename(i),"Dimmer Switch")
                        tRules.sensorno(i)  = extractBetween(tRules.rulename(i),"Switch "," ");
                    end
                end
            end

            %%%%
            %%%% Resource Links
            %%%%

            tableResourceLinks = struct2table(allResourceLinks);
            noRLs = size(tableResourceLinks);
            fieldX = fieldnames(allResourceLinks(1,1));

            % Create a standard format table of Resource Links
            sz = [noRLs(1,2) 4];
            varTypes = ["string", "string", "string", "string"];
            varNames = ["rlId", "rlname", "desc", "links"];
            tRlinks = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);
            tRlinks.rlId = fieldX;

            for i = 1:noRLs(1,2)
                tRlinks.rlname(i) = tableResourceLinks.(fieldX{i,1}).name;
                tRlinks.desc(i) = tableResourceLinks.(fieldX{i,1}).description;
                tRlinks.links(i) = strjoin(tableResourceLinks.(fieldX{i,1}).links, ', ');
                tRlinks.links(i) = strcat(tRlinks.links(i), ','); % add a final delimiter
            end

            %%%
            %%% Expand Rules table with Groups, Sensors, Schedules, Scenes & Resource Links data
            %%%

            for i = 1:noRules(1,2)

                tRules.RL(i) = '*';
                rlMatch = ['/rules/' num2str(tRules.ruleno(i)) ','];
                for n = 1:noRLs(1,2)
                    if contains(tRlinks.links(n), rlMatch)
                        tRules.RL(i) = tRlinks.desc(n);
                        break;
                    end
                end

                nxt = false;
                for j= 1:noGroups(1,2)
                    if tRules.groupno(i) > 0 && tRules.groupno(i) == tGroups.groupno(j)
                        tRules.groupdesc(i) = tGroups.groupname(j);
                        tRules.target(i) = "Group";
                        tRules.id(i) = tGroups.groupno(j);
                        tRules.description(i) = tGroups.groupname(j);
                        tRules.sensordesc(i) = '*';
                        tRules.scheduledesc(i) = '*';
                        tRules.scenedesc(i) = '*';
                        nxt = true;
                        break; % only need one match.. (optimisation)
                    end
                end
                if nxt
                    continue; % process next rule.. (optimisation)
                end
                for k = 1:noSensors(1,2)
                    if tRules.sensorno(i) == tSensors.sensorno(k)
                        tRules.sensordesc(i) = tSensors.sensorname(k);
                        if contains(tSensors.sensorname(k),"Dimmer")
                            tRules.target(i) = "Dimmer";
                        else
                            tRules.target(i) = "Sensor";
                        end
                        tRules.id(i) = tSensors.sensorno(k);
                        tRules.description(i) = tSensors.sensorname(k);
                        tRules.groupdesc(i) = '*';
                        tRules.scheduledesc(i) = '*';
                        tRules.scenedesc(i) = '*';
                        nxt = true;
                        break;
                    end
                end
                if nxt
                    continue;
                end
                for l = 1:noSchedules(1,2)
                    if tRules.scheduleno(i) == tSchedules.scheduleno(l)
                        tRules.scheduledesc(i) = tSchedules.schedulename(l);
                        tRules.target(i) = "Schedule";
                        tRules.id(i) = tSchedules.scheduleno(l);
                        tRules.description(i) = tSchedules.schedulename(l);
                        tRules.sensordesc(i) = '*';
                        tRules.groupdesc(i) = '*';
                        tRules.scenedesc(i) = '*';
                        nxt = true;
                        break;
                    end
                end
                if nxt
                    continue;
                end
                for m = 1:noScenes(1,2)
                    if extractAfter(tRules.action(i), '/scenes/') == tScenes.sceneID(m)
                        tRules.scenedesc(i) = strcat(tScenes.scenename(m)," / ",tScenes.type(m));
                        tRules.target(i) = "Scene";
                        tRules.id(i) = tScenes.sceneID(m);
                        tRules.description(i) = strcat(tScenes.scenename(m)," / ",tScenes.type(m));
                        tRules.sensordesc(i) = '*';
                        tRules.groupdesc(i) = '*';
                        tRules.scheduledesc(i) = '*';
                        nxt = true;
                        break;
                    end
                end
                if not(nxt) % no match found for any Rules category..
                    tRules.target(i) = "none";
                    tRules.id(i) = 0;
                    tRules.description(i) = '*';
                    tRules.sensordesc(i) = '*';
                    tRules.groupdesc(i) = '*';
                    tRules.scheduledesc(i) = '*';
                    tRules.scenedesc(i) = '*';
                end
            end

            tRsorted = sortrows(tRules,{'timestriggered','lasttriggered','created'},{'descend', 'descend','descend'});
            app.RulesTable.Data = tRsorted(:,1:11);
            app.ONRules.Value = rulesEnabled;
            app.NeverTriggered.Value = rulesNotTrig;

            saveAllData (app,allLights, allRules, allSensors, allGroups, ...
                allSchedules, allCapabilities, allResourceLinks, allScenes, config)

            app.Response.Value = toc;
        end

        %%%
        %%% Toggle Lights on/off - if On then switch Off & vice versa
        %%%

        function onOff = toggleLights(app, numLight, index, singleLight)
            lightsOn = str2double(app.LabelLightsOn.Text );
            options = weboptions('MediaType', 'application/json', 'RequestMethod', 'put');
            wrURL = sprintf('http://%s/api/%s/lights/%d/state', app.hueIPAddress,app.appID,numLight);
            if app.LightsTable.Data{index,2} == "Room" || singleLight
                if app.LightsTable.Data{index,8} == 1
                    state = struct('on', false);
                    lightsOn = lightsOn - 1;
                else
                    state = struct('on',true);
                    lightsOn = lightsOn + 1;
                end
                webwrite(wrURL, state, options);
                app.LabelLightsOn.Text = num2str(lightsOn);
            end
            if app.LightsTable.Data{index,8} == 1
                app.LightsTable.Data{index,8} = 0;
                onOff = 0;
            else
                app.LightsTable.Data{index,8} = 1;
                onOff = 1;
            end
        end

        %%%
        %%% Apply change to individual Light Brightness
        %%%

        function updBrightness(app, numLight)
            options = weboptions('MediaType', 'application/json', 'RequestMethod', 'put');
            wrURL = sprintf('http://%s/api/%s/lights/%d/state', app.hueIPAddress,app.appID,numLight);
            nbri = round(app.newBri * 2.54,0);
            state = struct('bri', nbri);
            webwrite(wrURL, state, options);
        end

        function highlightOnLights(app)
            noLights = size(app.LightsTable.Data);
            for i = 1:noLights(1,1)
                if app.LightsTable.Data{i,8} == 1
                    s1 = uistyle('BackgroundColor','white');
                else
                    s1 = uistyle('BackgroundColor','black');
                end
                addStyle(app.LightsTable,s1,'cell', [i 8]);
            end
        end

        %%%
        %%% Process Brightness change for Groups of Lights
        %%%

        function groupBrightness(app, noLights)
            selGrp = app.LightsTable.Data{app.cellSelIndices(1,1),1};
            for i = 1:noLights(1,1)
                if app.LightsTable.Data{i,1} == selGrp
                    numLight = app.LightsTable.Data{i,3};
                    for j = 1:noLights(1,1)
                        if app.LightsTable.Data{j,3} == numLight ...
                                && app.LightsTable.Data{j,7} ~= app.newBri
                            updBrightness (app, numLight);
                            for k = 1:noLights(1,1) % update all brightness table entries for this light
                                if app.LightsTable.Data{k,3} == numLight
                                    app.LightsTable.Data{k,7} = app.newBri;
                                end
                            end
                            break
                        end
                    end
                end
            end
        end

        %%%
        %%%  Process changes to Light Brightness
        %%%

        function results = newBrightness(app, event)
            indices = event.Indices;
            try
                if event.NewData == event.PreviousData
                    results = "Brightness edited but not changed.";
                    return;
                end
                newData = event.NewData;
                if newData > 100
                    newData = 100;
                elseif newData < 1
                    newData = 1;
                end
            catch EM
                results = "Brightness selected.";
                return;
            end
            app.newBri = newData;
            noLights = size(app.LightsTable.Data);
            if app.cellSelIndices(1,2) == 1 % Group selection previously processed
                groupBrightness (app, noLights);
                results = strcat(app.LightsTable.Data{app.cellSelIndices(1,1),1}, " lights brightness changed.");
            elseif indices(1,2) == 7 % Single Brightness Changed
                numLight = app.LightsTable.Data{indices(1,1),3};
                updBrightness (app, numLight);
                for j = 1:noLights(1,1)
                    if app.LightsTable.Data{j,3} == numLight
                        app.LightsTable.Data{j,7} = newData;
                    end
                end
                results = strcat(app.LightsTable.Data{app.cellSelIndices(1,1),4}, " light brightness changed.");
            end
            app.cellSelIndices = event.Indices;
        end

        %%%
        %%% Update Light Colour
        %%%

        function updColour(app, numLight)
            options = weboptions('MediaType', 'application/json', 'RequestMethod', 'put');
            wrURL = sprintf('http://%s/api/%s/lights/%d/state', app.hueIPAddress,app.appID,numLight);
            state = struct('xy', app.xyColour);
            webwrite(wrURL, state, options);
        end

        function groupColour (app, noLights)
            selGrp = app.LightsTable.Data{app.cellSelIndices(1,1),1};
            for i = 1:noLights(1,1)
                if app.LightsTable.Data{i,1} == selGrp
                    numLight = app.LightsTable.Data{i,3};
                    updColour(app, numLight);
                end
            end
        end

        function results = newColour(app, event)
            indices = event.Indices;
            noLights = size(app.LightsTable.Data);
            if app.cellSelIndices(1,2) == 1 % Group selection previously processed
                groupColour(app, noLights);
                results = strcat(app.LightsTable.Data{app.cellSelIndices(1,1),1}, " lights colour set to ", app.newColr,".");
            elseif indices(1,2) == 7 % Single Colour Changed
                numLight = app.LightsTable.Data{indices(1,1),3};
                updColour(app, numLight);
                results = strcat(app.LightsTable.Data{app.cellSelIndices(1,1),4}, " light colour set to ", app.newColr, ".");
            end
            app.cellSelIndices = event.Indices;
        end

        %%%
        %%% Connect to Hue Bridge
        %%%

        function [errCode, hueIPAddress, appID, allLights,   ...
                allRules, allSensors, allGroups, allSchedules, allCapabilities, allResourceLinks, allScenes, config] = ...
                connectToBridge(app)

            % Initialise all returned data items

            appID = '';
            allLights = [];
            allRules = [];
            allSensors = [];
            allGroups = [];
            allSchedules = [];
            allCapabilities = [];
            allResourceLinks = [];
            allScenes = [];
            config = [];

            fprintf('Looking up IP address...');
            [errCode, hueIPAddress] = getBridgeIP(app);
            % [hueID, hueIPAddress] = getBridgeIP(app); % prepare for HTTPS migration..

            if errCode ~= 0
                return
            end

            fprintf('; Hue IP = %s\n',hueIPAddress);

            if ispc
                app.fName = [app.dataRepository '\userNameHue.mat'];
            else
                app.fName = [app.dataRepository '/userNameHue.mat'];
            end

            fprintf('Checking if already registered to the Hue Bridge...')

            try
                load(app.fName);  % userNameHue
                appID     = hueUser;
                url        = sprintf('http://%s/api/%s/', hueIPAddress,appID);
                deviceInfo = webread(url); % check if authorised
                notInBridgeWhitelist = isfield(deviceInfo,'error');
            catch MSG
                % Cater for lack of internet..
                if contains(MSG.message,'not connect to server')
                    errCode = 999;
                    warning('No internet connection.');
                    return;
                end
                notInBridgeWhitelist = true;
            end

            if notInBridgeWhitelist
                fprintf('Not registered with Bridge. Attempting connection.\n')
                appID = registerWithBridge(app, hueIPAddress, app.fName);
                if ~isempty(appID)
                    fprintf('Connection with Hue Bridge completed. Added to Whitelist. \n')
                else
                    fprintf('Connection process terminated. \n')
                    errCode = 888;
                    return;
                end
            else
                fprintf('Connection with Hue Bridge established; using registered user-name (appID)\n')
            end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%% Gather Bridge data to return for further processing..
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            url        = sprintf('http://%s/api/%s/lights', hueIPAddress,appID);
            % url        = sprintf('https://%s/bridgeid/%s/lights', hueID, appID); % prepare for poss future HTTPS migration..
            response = webread(url);
            if ~isfield(response,'error')
                allLights = response;
            else % warning for first read failure to aid diagnosis - error for any subsequent failures
                warning ('** Unable to access Lights details **');
                save([app.dataRepository 'diagnostics.mat'],"response");
                disp(url);
            end

            url = sprintf('http://%s/api/%s/rules', hueIPAddress,appID);
            response = webread(url);
            if ~isfield(response,'error')
                allRules = response;
            else
                error ('** Unable to access Rules details **');
            end

            url = sprintf('http://%s/api/%s/sensors', hueIPAddress,appID);
            response = webread(url);
            if ~isfield(response,'error')
                allSensors = response;
            else
                error ('** Unable to access Sensors details **');
            end

            url = sprintf('http://%s/api/%s/groups', hueIPAddress,appID);
            response = webread(url);
            if ~isfield(response,'error')
                allGroups = response;
            else
                error ('** Unable to access Groups details **');
            end

            url = sprintf('http://%s/api/%s/schedules', hueIPAddress,appID);
            response = webread(url);
            if ~isfield(response,'error')
                allSchedules = response;
            else
                error ('** Unable to access Schedules details **');
            end

            url = sprintf('http://%s/api/%s/capabilities', hueIPAddress,appID);
            response = webread(url);
            if ~isfield(response,'error')
                allCapabilities = response;
            else
                error ('** Unable to access Capabilities details **');
            end


            url = sprintf('http://%s/api/%s/config', hueIPAddress,appID);
            response = webread(url);
            if ~isfield(response,'error')
                config = response;
            else
                error ('** Unable to access Config details **');
            end

            url = sprintf('http://%s/api/%s/resourcelinks', hueIPAddress,appID);
            response = webread(url);
            if ~isfield(response,'error')
                allResourceLinks = response;
            else
                error ('** Unable to access Resourcelinks details **');
            end

            url = sprintf('http://%s/api/%s/scenes', hueIPAddress,appID);
            response = webread(url);
            if ~isfield(response,'error')
                allScenes = response;
            else
                error ('** Unable to access Scenes details **');
            end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            fprintf('\n\n');
        end

        %%%
        %%% New Bridge registration - process for adding new user to WhiteList
        %%%

        function appID = registerWithBridge(app, hueIPAddress, fNameSAVE)
            appID = [];
            app.DiagnosticsMsgTextArea.Value = 'Attempting to pair with the Bridge..';
            fprintf('\nAttempting to pair with the Bridge..');
            url  = sprintf('http://%s/api', hueIPAddress);
            options = weboptions('MediaType', 'application/json');
            deviceType = struct('devicetype', 'SPF_Matlab_API');
            response = webwrite (url, deviceType, options);

            if isfield(response,'success')
                finished = true;
            else
                finished = false;
            end

            if ~finished

                Msgbox = uifigure;
                message = "Need to obtain a valid Username to connect to Bridge; Proceed with Pairing? (Will need to press the link button on the bridge.)";
                selection1 = uiconfirm(Msgbox,message,"Bridge Registration Process", ...
                    "Options",["Yes","No (Cancel)"],...
                    "DefaultOption",1,"CancelOption",2,....
                    "Icon","question");
                switch selection1
                    case 'Yes'
                        message = "Press the Link Button on the Hue Bridge within 60 seconds.";
                        selection2 = uiconfirm(Msgbox,message,"Bridge Registration Process", ...
                            "Options",["Continue","Cancel"],...
                            "DefaultOption",1,"CancelOption",2,....
                            "Icon","info");
                        switch selection2
                            case 'Continue'
                                uialert(Msgbox,"Press the Link button on the Bridge","OK");

                                app.DiagnosticsMsgTextArea.Value = 'Waiting for Link Button press..';
                                drawnow
                            case 'Cancel'
                                app.DiagnosticsMsgTextArea.Value = 'Cancelled.  Not paired.';
                                drawnow
                                pause(3)
                                delete(Msgbox);
                                return
                        end
                    case 'No (Cancel)'
                        app.DiagnosticsMsgTextArea.Value = 'Cancelled.  Not paired.';
                        drawnow
                        pause(3)
                        delete(Msgbox);
                        return
                end
            end

            tic
            while ~finished && toc < 60
                try
                    response = webwrite (url, deviceType, options);
                    if isfield(response,'success')
                        finished = true;
                    end
                catch % wait for link button being pressed, unless it's a real error
                    if contains(response.error.description, 'link button not pressed')
                        pause(5)
                    else
                        fprintf('\n* Unable to communicate with the Bridge *\n');
                        app.DiagnosticsMsgTextArea.Value = 'Unable to communicate with the Bridge.  Not paired.';
                        save([app.dataRepository 'diagnostics.mat'],"response");
                        pause(3)
                        delete(Msgbox);
                        return
                    end
                end
            end

            if toc >= 60
                fprintf('\n* Link button not pressed within 60 seconds.  Timed out. *\n');
                app.DiagnosticsMsgTextArea.Value = 'Link button not pressed within 60 seconds.  Timed out.';
                drawnow
                pause(3)
                delete(Msgbox);
                return
            end

            appID = response.success.username;
            hueUser = appID;
            save(fNameSAVE,'hueUser');
            app.DiagnosticsMsgTextArea.Value = 'Bridge registration successful (added to Whitelist).  Restarting the App in 5 seconds...';
            pause(5)
            delete(Msgbox);
            restart(app);
        end

        %%%
        %%% Get the Bridge IP address - if not available or no longer valid then obtain a new one..
        %%%

        function [errCode, hueIPAddress] = getBridgeIP(app)

            % >>>>>>>> First try to use previously stored IP address to save unnecessary external webreads
            errCode = 0;
            try
                if ispc
                    configFile = [app.dataRepository '\config.mat'];
                    userFile = [app.dataRepository '\userNameHue.mat'];
                else
                    configFile = [app.dataRepository '/config.mat'];
                    userFile = [app.dataRepository '/userNameHue.mat'];
                end
                app.fName = configFile;
                load(app.fName);
                hueIPAddress = config.ipaddress;
                app.fName =  userFile;
                load(app.fName);
                app.appID     = hueUser;
                fprintf('Using previously stored IP address from config.mat saved on %s', config.localtime);
                url        = sprintf('http://%s/api/%s/config', hueIPAddress,app.appID);
                % check that the details still work..
                webread(url);
            catch MSG
                disp(MSG.message)
                % Try to get fresh details from the web
                fprintf('\nIP address changed.  Obtaining new IP address..');
                url = 'https://discovery.meethue.com/';
                try
                    data = webread(url);
                    if isempty(data) % Failed to access meethue.com..
                        errCode = 777;
                        return;
                    end
                    fprintf(' OK - done.. New Hue IP = %s', data.internalipaddress);
                    hueIPAddress = data.internalipaddress;
                catch
                    % Cater for lack of internet..
                    if contains(MSG.message,'not connect to server') || contains(MSG.message,'not access server')
                        errCode = 999;
                        return;
                    end
                end
            end
        end

        %%%
        %%% Saves all Bridge data for external analysis (not obligatory)
        %%%


        function saveAllData(app,allLights, allRules, allSensors, allGroups, ...
                allSchedules, allCapabilities, allResourceLinks, allScenes, config)
            if ispc
                % Save all data for subsequent analysis
                save([app.dataRepository '\allLightsData.mat'],"allLights");
                save([app.dataRepository '\allRulesData.mat'],"allRules");
                save([app.dataRepository '\allSensorsData.mat'],"allSensors");
                save([app.dataRepository '\allGroupsData.mat'],"allGroups");
                save([app.dataRepository '\allSchedulesData.mat'],"allSchedules");
                save([app.dataRepository '\allCapabilitiesData.mat'],"allCapabilities");
                save([app.dataRepository '\allScenesData.mat'],"allScenes");
                save([app.dataRepository '\allResourceLinksData.mat'],"allResourceLinks");
                save([app.dataRepository '\config.mat'],"config");
            else
                save([app.dataRepository '/allLightsData.mat'],"allLights");
                save([app.dataRepository '/allRulesData.mat'],"allRules");
                save([app.dataRepository '/allSensorsData.mat'],"allSensors");
                save([app.dataRepository '/allGroupsData.mat'],"allGroups");
                save([app.dataRepository '/allSchedulesData.mat'],"allSchedules");
                save([app.dataRepository '/allCapabilitiesData.mat'],"allCapabilities");
                save([app.dataRepository '/allScenesData.mat'],"allScenes");
                save([app.dataRepository '/allResourceLinksData.mat'],"allResourceLinks");
                save([app.dataRepository '/config.mat'],"config");
            end

            listing = dir("**");
            tbl = struct2table(listing);
            tbl.date = datetime(tbl.datenum,ConvertFrom="datenum");
            tbl = removevars(tbl,"datenum");
            dets = tbl(tbl.name == "allCapabilitiesData.mat",3);
            app.LastBridgeDataRefreshTextArea.Value = string(dets.date(1,1));
        end

        %%%
        %%% Restart the app after whitelist addition or IP address refresh
        %%%


        function restart(app)
            startupFcn(app);              % Launch a new instance of the app
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.dataRepository = pwd; % CURRENT directory (* NOT necessarily the directory from which the App was launched *)
            if ispc
                repositoryName = '\HueData';
            else
                repositoryName = '/HueData';
            end
            fprintf('\n%s starting Up in Directory: %s\nLooking for existing Hue Data Repository %s...', mfilename, pwd, repositoryName);
            %%%
            %%% First check whether the current directory is already the data repository..
            %%%
            if ~contains(app.dataRepository,repositoryName)
                app.dataRepository = [app.dataRepository repositoryName];
                % Now check if there is a data repository sub-directory...
                if ~exist(app.dataRepository, 'dir')
                    mkdir(app.dataRepository);
                    fprintf('Not found.  Created new Hue Data Repository: %s\n', app.dataRepository);
                else
                    fprintf('Found.\n');
                end
            else
                fprintf('Found.\n');
            end
            fileName = 'userNameHue.mat';
            fprintf('Looking for file: %s in %s...', fileName, app.dataRepository);
            usernameFile = fullfile(app.dataRepository, fileName);
            % Check if file exists
            if ~exist(usernameFile, 'file')
                fprintf('not found.  Attempting to copy from main Hue directory..');
                if ispc
                    sourceDir = 'C:\Users\spfol\OneDrive\Matlab2025\Current_r2025\Hue';
                    sourceFile = fullfile(sourceDir, fileName);
                else
                    sourceDir = '/Users/stephenfolmer/Library/CloudStorage/OneDrive-Personal/Matlab2025/Current_r2025/Hue'; % directorty containing master of userNameHue.mat
                    sourceFile = fullfile(sourceDir, fileName);
                end
                targetFile = fullfile(app.dataRepository, fileName);
                % Check if file exists
                if exist(sourceFile, 'file')
                    % Copy the file
                    copyfile(sourceFile, targetFile);
                    fprintf('Source File copied to: %s\n', targetFile);
                else
                    fprintf('\n** Master Source File not found: %s **\n', sourceFile);
                end
            else
                fprintf('Found.\n');
            end

            errCode = getStats (app, 'startup');
            if errCode == 0
                errCode = getStats (app, 'refresh');
            end
            dt = datetime('now', 'Format', 'dd/MM/yy HH:mm:ss');
            strDt = string(dt);
            app.DiagnosticsTimeTextArea.Value = strDt;
            if errCode == 0
                app.DiagnosticsMsgTextArea.Value = 'Bridge data loaded.';
                app.successfulStart = true;
            else
                app.DiagnosticsMsgTextArea.Value = [app.DiagnosticsMsgTextArea.Value '  Start-up failed.'];
                app.successfulStart = false;
            end
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)

            closereq;
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            % If not yet successfully started (e.g. lack of internet) re-initiate startup processing..
            if ~app.successfulStart
                startupFcn(app);
            else
                clc

                app.RefreshButton.Visible ="off";
                app.DiagnosticsMsgTextArea.Value = '';
                app.LastBridgeDataRefreshTextArea.Value = 'Reading from Bridge..';
                prevColour = app.LastBridgeDataRefreshTextArea.FontColor;
                app.LastBridgeDataRefreshTextArea.FontColor = "white";
                app.DiagnosticsMsgTextArea.Value = 'Extracting details from the Bridge..';
                app.Image.Visible = 'off';
                drawnow;
                errCode = getStats (app, 'refresh');
                app.LastBridgeDataRefreshTextArea.FontColor = prevColour;
                dt = datetime('now', 'Format', 'dd/MM/yy HH:mm:ss');
                strDt = string(dt);
                app.DiagnosticsTimeTextArea.Value = strDt;
                if errCode == 0
                    app.DiagnosticsMsgTextArea.Value = 'Fresh data retrieved from the Bridge.';
                else
                    app.DiagnosticsMsgTextArea.Value = [app.DiagnosticsMsgTextArea.Value '  Refresh failed.'];
                    app.successfulStart = false;
                end
                app.RefreshButton.Visible = "on";
                app.RefreshButton.Tooltip = '';
                app.Image.Visible = 'on';

            end
        end

        % Cell selection callback: LightsTable
        function LightsTableCellSelection(app, event)
            pause(0.01);  % 10ms delay allows CellEditCallback to finish
            dt = datetime('now', 'Format', 'dd/MM/yy HH:mm:ss');
            strDt = string(dt);
            app.DiagnosticsTimeTextArea.Value = strDt;
            app.cellSelIndices = event.Indices;
            noLights = size(app.LightsTable.Data);
            if app.cellSelIndices(1,2) == 7 % Ignore if Brightness selected
                app.DiagnosticsMsgTextArea.Value = newBrightness(app, event);
                return;
            end
            app.Image.Visible = 'off';
            drawnow;
            if app.cellSelIndices(1,2) == 1 % Group selected
                if app.prevEventName == "KeyPress" && app.editGroup == app.LightsTable.Data{app.cellSelIndices(1,1),1}
                    % brightness changed - only update brightness; don't toggle group lights
                    if app.xyColour(1,1) == 0
                        groupBrightness(app, noLights);
                        app.DiagnosticsMsgTextArea.Value = strcat(app.LightsTable.Data{app.cellSelIndices(1,1),1}, " brightness changed.");
                    else
                        groupColour(app, noLights);
                        app.DiagnosticsMsgTextArea.Value = strcat(app.LightsTable.Data{app.cellSelIndices(1,1),1}, " colour set to ", app.newColr,".");
                    end
                    app.editGroup = 'none'; % reset so that the same group will toggle if selected next
                else
                    selGrp = app.LightsTable.Data{app.cellSelIndices(1,1),1};
                    grpType = app.LightsTable.Data{app.cellSelIndices(1,1),2};
                    for i = 1:noLights(1,1)
                        if app.LightsTable.Data{i,1} == selGrp ...
                                && app.LightsTable.Data{i,2} == grpType
                            numLight = app.LightsTable.Data{i,3};
                            onOff = toggleLights(app, numLight, i, true);
                            for j = 1:noLights(1,1)
                                if app.LightsTable.Data{j,3} == numLight
                                    app.LightsTable.Data{j,8} = onOff;
                                end
                            end
                        end
                    end
                    app.DiagnosticsMsgTextArea.Value = strcat(app.LightsTable.Data{app.cellSelIndices(1,1),1}, " lights toggled.");
                end
            else
                if app.prevEventName ~= "KeyPress" % ignore toggle if brightness was just updated
                    numLight = app.LightsTable.Data{app.cellSelIndices(1,1),3};
                    onOff = toggleLights(app, numLight, app.cellSelIndices(1,1), true);
                    for j = 1:noLights(1,1)
                        if app.LightsTable.Data{j,3} == numLight
                            app.LightsTable.Data{j,8} = onOff;
                        end
                    end
                    app.DiagnosticsMsgTextArea.Value = strcat(app.LightsTable.Data{app.cellSelIndices(1,1),4}, " light toggled.");
                end
            end
            highlightOnLights(app);
            app.prevEventName = event.EventName;
            app.xyColour = [0,0];
            app.Image.Visible = 'on';
        end

        % Button pushed function: ToggleALLLightsButton
        function ToggleALLLightsButtonPushed(app, event)
            app.DiagnosticsMsgTextArea.Value = 'Toggling ALL Lights..';
            app.Image.Visible = 'off';
            drawnow;
            noLights = size(app.LightsTable.Data);
            for i = 1:noLights(1,1)
                numLight = app.LightsTable.Data{i,3};
                toggleLights(app, numLight, i, false);
            end
            dt = datetime('now', 'Format', 'dd/MM/yy HH:mm:ss');
            strDt = string(dt);
            app.DiagnosticsTimeTextArea.Value = strDt;
            app.DiagnosticsMsgTextArea.Value = 'ALL lights toggled.';
            highlightOnLights(app);
            app.prevEventName = event.EventName;
            app.Image.Visible = 'on';
        end

        % Button pushed function: AllLightsOn
        function AllLightsOnButtonPushed(app, event)
            app.DiagnosticsMsgTextArea.Value = 'Switching ALL Lights ON..';
            app.Image.Visible = 'off';
            drawnow;
            lightsOn = str2double(app.LabelLightsOn.Text);
            options = weboptions('MediaType', 'application/json', 'RequestMethod', 'put');
            state = struct('on',true);
            noLights = size(app.LightsTable.Data);
            for i = 1:noLights(1,1)
                if app.LightsTable.Data{i,8} == 0
                    app.LightsTable.Data{i,8} = 1;
                    if app.LightsTable.Data{i,2} == "Room"
                        numLight = app.LightsTable.Data{i,3};
                        wrURL = sprintf('http://%s/api/%s/lights/%d/state', app.hueIPAddress,app.appID,numLight);
                        webwrite(wrURL, state, options);
                        lightsOn = lightsOn + 1;
                    end
                end
            end
            dt = datetime('now', 'Format', 'dd/MM/yy HH:mm:ss');
            strDt = string(dt);
            app.DiagnosticsTimeTextArea.Value = strDt;
            app.DiagnosticsMsgTextArea.Value = 'ALL lights ON.';
            highlightOnLights(app);
            app.prevEventName = event.EventName;
            app.LabelLightsOn.Text = num2str(lightsOn);
            app.Image.Visible = 'on';
        end

        % Button pushed function: AllLightsOff
        function AllLightsOffButtonPushed(app, event)
            app.DiagnosticsMsgTextArea.Value = 'Switching ALL Lights OFF..';
            app.Image.Visible = 'off';
            drawnow;
            options = weboptions('MediaType', 'application/json', 'RequestMethod', 'put');
            state = struct('on',false);
            noLights = size(app.LightsTable.Data);
            for i = 1:noLights(1,1)
                if app.LightsTable.Data{i,8} == 1
                    app.LightsTable.Data{i,8} = 0;
                    if app.LightsTable.Data{i,2} == "Room"
                        numLight = app.LightsTable.Data{i,3};
                        wrURL = sprintf('http://%s/api/%s/lights/%d/state', app.hueIPAddress,app.appID,numLight);
                        webwrite(wrURL, state, options);
                    end
                end
            end
            dt = datetime('now', 'Format', 'dd/MM/yy HH:mm:ss');
            strDt = string(dt);
            app.DiagnosticsTimeTextArea.Value = strDt;
            app.DiagnosticsMsgTextArea.Value = 'ALL lights OFF.';
            highlightOnLights(app);
            app.prevEventName = event.EventName;
            app.LabelLightsOn.Text = '0';
            app.Image.Visible = 'on';
        end

        % Cell edit callback: LightsTable
        function LightsTableCellEdit(app, event)
            app.Image.Visible = 'off';
            drawnow;
            app.cellSelIndices = event.Indices;
            app.editGroup = app.LightsTable.Data{event.Indices(1,1),1};
            dt = datetime('now', 'Format', 'dd/MM/yy HH:mm:ss');
            strDt = string(dt);
            app.DiagnosticsTimeTextArea.Value = strDt;
            app.xyColour = [0,0];
            app.newColr = '';

            if isnan(event.NewData)
                app.LightsTable.Data{event.Indices(1,1),7} = event.PreviousData; % restore previous numerical value
                switch upper(event.EditData)
                    case 'B'
                        app.newColr = 'blue';
                        app.xyColour= [0.15, 0.06];
                    case 'C'
                        app.newColr = 'cyan';
                        app.xyColour= [0.17, 0.34];
                    case 'G'
                        app.newColr = 'green';
                        app.xyColour= [0.17, 0.7];
                    case 'Y'
                        app.newColr = 'yellow';
                        app.xyColour= [0.44, 0.51];
                    case 'O'
                        app.newColr = 'orange';
                        app.xyColour= [0.56, 0.42];
                    case 'R'
                        app.newColr = 'red';
                        app.xyColour= [0.7, 0.3];
                    case 'P'
                        app.newColr = 'purple';
                        app.xyColour= [0.25, 0.1];
                    case 'M'
                        app.newColr = 'magenta';
                        app.xyColour= [0.38, 0.17];
                    case 'W'
                        app.newColr = 'white';
                        app.xyColour= [0.31, 0.33];
                    otherwise
                        app.DiagnosticsMsgTextArea.Value = 'Invalid colour.  Must be: b(lue), c(yan), g(reen), y(ellow), o(range), r(ed), p(urple), m(agenta) or w(hite).';
                        return;
                end
                app.DiagnosticsMsgTextArea.Value = newColour (app, event);
            else
                app.DiagnosticsMsgTextArea.Value = newBrightness (app, event);
            end
            app.Image.Visible = 'on';
        end

        % Selection changed function: LightsTable
        function LightsTableSelectionChanged(app, event)
            app.prevCellSel = event.PreviousSelection;
            if isempty(app.prevCellSel)
                app.prevCellSel = [0 0];
            end
        end

        % Key press function: LightsTable
        function LightsTableKeyPressed(app, event)
            app.lastKey = event.Key;
            switch app.lastKey
                case "return"
                    app.prevEventName = 'none';
                    app.editGroup = '';
                case "tab"
                    app.prevEventName = 'none';
                    app.editGroup = '';
                otherwise
                    app.prevEventName = event.EventName;
            end
        end

        % Key press function: PhilipsHueDashboardv27UIFigure
        function PhilipsHueDashboardv27UIFigureKeyPress(app, event)
            app.lastKey = event.Key;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.PhilipsHueDashboardv27UIFigure = uifigure('Visible', 'off');
            app.PhilipsHueDashboardv27UIFigure.Name = 'Philips Hue Dashboard (v2.7)';
            app.PhilipsHueDashboardv27UIFigure.Icon = fullfile(pathToMLAPP, 'huelogo.png');
            app.PhilipsHueDashboardv27UIFigure.KeyPressFcn = createCallbackFcn(app, @PhilipsHueDashboardv27UIFigureKeyPress, true);
            app.ExitButton = uibutton(app.PhilipsHueDashboardv27UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.PhilipsHueBridgeDashboardLabel = uilabel(app.PhilipsHueDashboardv27UIFigure);
            app.PhilipsHueBridgeDashboardLabel.Text = 'Philips Hue Bridge Dashboard';
            app.RefreshButton = uibutton(app.PhilipsHueDashboardv27UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Tooltip = {'Refresh the display with latest Bridge data.'};
            app.RefreshButton.Text = 'Refresh';
            app.IPAddressLabel_3 = uilabel(app.PhilipsHueDashboardv27UIFigure);
            app.IPAddressLabel_3.Text = 'API v.';
            app.IPAddressLabel = uilabel(app.PhilipsHueDashboardv27UIFigure);
            app.IPAddressLabel.Text = 'IP Address';
            app.ZigbeeChannelLabel = uilabel(app.PhilipsHueDashboardv27UIFigure);
            app.ZigbeeChannelLabel.Text = 'Zigbee Chan';
            app.LastFirmwareUpdateSoftwareVersionTextAreaLabel = uilabel(app.PhilipsHueDashboardv27UIFigure);
            app.LastFirmwareUpdateSoftwareVersionTextAreaLabel.Text = 'Last  Firmware Update / Software Version';
            app.IPAddressLabel_4 = uilabel(app.PhilipsHueDashboardv27UIFigure);
            app.IPAddressLabel_4.Text = 'Auto Update';
            app.PreviousStatusRefreshLabel = uilabel(app.PhilipsHueDashboardv27UIFigure);
            app.PreviousStatusRefreshLabel.Text = {'Last Bridge'; 'Data Refresh'};
            app.ZigbeeChannelLabel_2 = uilabel(app.PhilipsHueDashboardv27UIFigure);
            app.ZigbeeChannelLabel_2.Text = {'Refresh'; 'Response'};
            app.APIversion = uitextarea(app.PhilipsHueDashboardv27UIFigure);
            app.APIversion.Editable = 'off';
            app.APIversion.Tooltip = {'Current Bridge API version.'};
            app.IPAddress = uitextarea(app.PhilipsHueDashboardv27UIFigure);
            app.IPAddress.Editable = 'off';
            app.IPAddress.Tooltip = {'Current Bridge IP Address.'};
            app.ZigbeeChannel = uieditfield(app.PhilipsHueDashboardv27UIFigure, 'numeric');
            app.ZigbeeChannel.Editable = 'off';
            app.ZigbeeChannel.Tooltip = {'The Zigbee Channel selected in the Bridge settings'};
            app.LastFirmwareUpdate = uitextarea(app.PhilipsHueDashboardv27UIFigure);
            app.LastFirmwareUpdate.Editable = 'off';
            app.LastFirmwareUpdate.Tooltip = {'Time and version of the last Bridge Software update.'};
            app.AutoSWUpdate = uitextarea(app.PhilipsHueDashboardv27UIFigure);
            app.AutoSWUpdate.Editable = 'off';
            app.AutoSWUpdate.Tooltip = {'Indicates whether the Bridge settings have Software Auto-Update enabled.'};
            app.LastBridgeDataRefreshTextArea = uitextarea(app.PhilipsHueDashboardv27UIFigure);
            app.LastBridgeDataRefreshTextArea.Editable = 'off';
            app.LastBridgeDataRefreshTextArea.Tooltip = {'Timestamp of last Bridge Data Refresh.'};
            app.Response = uieditfield(app.PhilipsHueDashboardv27UIFigure, 'numeric');
            app.Response.ValueDisplayFormat = '%.0f';
            app.Response.Editable = 'off';
            app.Response.Tooltip = {'Response time (seconds) for last Refresh.'};
            app.IPAddressLabel_2 = uilabel(app.PhilipsHueDashboardv27UIFigure);
            app.IPAddressLabel_2.Text = 'Bridge ID';
            app.BridgeID = uitextarea(app.PhilipsHueDashboardv27UIFigure);
            app.BridgeID.Editable = 'off';
            app.BridgeID.Tooltip = {'The Bridge ID specified in the configuration. '};
            app.SWversion = uitextarea(app.PhilipsHueDashboardv27UIFigure);
            app.SWversion.Editable = 'off';
            app.Label = uilabel(app.PhilipsHueDashboardv27UIFigure);
            app.Label.Text = '/';
            app.SWUpDateAvail = uitextarea(app.PhilipsHueDashboardv27UIFigure);
            app.SWUpDateAvail.Editable = 'off';
            app.SWUpDateAvail.Tooltip = {'Indicates whether the Bridge has pending Software Updates.'};
            app.SensorTemperaturesPanel = uipanel(app.PhilipsHueDashboardv27UIFigure);
            app.SensorTemperaturesPanel.Title = 'Sensor Temperatures (max/min)';
            app.CMSensorsLabel = uilabel(app.SensorTemperaturesPanel);
            app.CMSensorsLabel.Text = 'Comfort      18-21';
            app.CMSensors = uieditfield(app.SensorTemperaturesPanel, 'numeric');
            app.CMSensors.Editable = 'off';
            app.CLSensors = uieditfield(app.SensorTemperaturesPanel, 'numeric');
            app.CLSensors.Editable = 'off';
            app.COSensors = uieditfield(app.SensorTemperaturesPanel, 'numeric');
            app.COSensors.Editable = 'off';
            app.WASensors = uieditfield(app.SensorTemperaturesPanel, 'numeric');
            app.WASensors.Editable = 'off';
            app.HOSensors = uieditfield(app.SensorTemperaturesPanel, 'numeric');
            app.HOSensors.Editable = 'off';
            app.HotLabel = uilabel(app.SensorTemperaturesPanel);
            app.HotLabel.Text = 'Hot      > 30';
            app.WarmLabel = uilabel(app.SensorTemperaturesPanel);
            app.WarmLabel.Text = 'Warm   > 21';
            app.CoolLabel = uilabel(app.SensorTemperaturesPanel);
            app.CoolLabel.Text = 'Cool     < 18';
            app.ColdLable = uilabel(app.SensorTemperaturesPanel);
            app.ColdLable.Text = 'Cold     < 10';
            app.FreezingLable = uilabel(app.SensorTemperaturesPanel);
            app.FreezingLable.Text = 'Freezing < 0';
            app.FRSensors = uieditfield(app.SensorTemperaturesPanel, 'numeric');
            app.FRSensors.Editable = 'off';
            app.SensorsandSwitchesLabel = uilabel(app.PhilipsHueDashboardv27UIFigure);
            app.SensorsandSwitchesLabel.Tooltip = {'Temperatures in Celcius and Battery percentage remaining.'; 'Last Events pertain to triggered detections or programmed automations for Sensors and manual interactions with Switches/dimmers.'};
            app.SensorsandSwitchesLabel.Text = 'Sensors and Switches';
            app.TempsTable = uitable(app.PhilipsHueDashboardv27UIFigure);
            app.TempsTable.ColumnName = {'Temp'; 'Batt'; 'Name (model)'; 'Last Event'};
            app.TempsTable.RowName = {};
            app.TempsTable.ColumnSortable = true;
            app.LightsLabel_2 = uilabel(app.PhilipsHueDashboardv27UIFigure);
            app.LightsLabel_2.Tooltip = {'TOGGLE switches lights between ON and OFF state.'; '*'; 'Click on a Group cell (Column 1) to Toggle a lights Group OR Toggle individual lights by clicking light-specific cells (cols 2-6 & 8).  '; '*'; 'To apply brightness or colour changes to light Groups:'; '1) Type brightness percentage or colour character in Col 7 (Bri-Col) - do NOT hit Enter or Tab.'; '2) Click on a Group cell in the SAME row as the above changes were made. '; '*'; 'To apply brightness or colour changes to ONE light:'; '1) Type brightness percentage or colour character in Col 7 (''Bri-Col'') '; '2) Hit Enter or Tab.'; '*'; 'Valid Colour values are:  r(ed), g(reen), b(lue) , y(ellow), w(hite), p(urple), o(range), c(yan), m(agenta).'};
            app.LightsLabel_2.Text = 'Lights';
            app.ToggleALLLightsButton = uibutton(app.PhilipsHueDashboardv27UIFigure, 'push');
            app.ToggleALLLightsButton.ButtonPushedFcn = createCallbackFcn(app, @ToggleALLLightsButtonPushed, true);
            app.ToggleALLLightsButton.Text = {'Toggle ALL'; 'Lights'};
            app.AllLightsOff = uibutton(app.PhilipsHueDashboardv27UIFigure, 'push');
            app.AllLightsOff.ButtonPushedFcn = createCallbackFcn(app, @AllLightsOffButtonPushed, true);
            app.AllLightsOff.Text = 'ALL OFF';
            app.AllLightsOn = uibutton(app.PhilipsHueDashboardv27UIFigure, 'push');
            app.AllLightsOn.ButtonPushedFcn = createCallbackFcn(app, @AllLightsOnButtonPushed, true);
            app.AllLightsOn.Text = 'ALL ON';
            app.SelectcellstotoggleonoffOReditBriColforbrightnessColourLabel = uilabel(app.PhilipsHueDashboardv27UIFigure);
            app.SelectcellstotoggleonoffOReditBriColforbrightnessColourLabel.Tooltip = {'TOGGLE switches lights between ON and OFF state.'; '*'; 'Click on a Group cell (Column 1) to Toggle a lights Group OR Toggle individual lights by clicking light-specific cells (cols 2-6 & 8).  '; '*'; 'To apply brightness or colour changes to light Groups:'; '1) Type brightness percentage or colour character in Col 7 (Bri-Col) - do NOT hit Enter or Tab.'; '2) Click on a Group cell in the SAME row as the above changes were made. '; '*'; 'To apply brightness or colour changes to ONE light:'; '1) Type brightness percentage or colour character in Col 7 (''Bri-Col'') '; '2) Hit Enter or Tab.'; '*'; 'Valid Colour values are:  r(ed), g(reen), b(lue) , y(ellow), w(hite), p(urple), o(range), c(yan), m(agenta).'};
            app.SelectcellstotoggleonoffOReditBriColforbrightnessColourLabel.Text = 'Select cells to toggle on/off  OR  edit ''Bri -Col'' for brightness % & Colour';
            app.LightsTable = uitable(app.PhilipsHueDashboardv27UIFigure);
            app.LightsTable.ColumnName = {'Group'; 'Grp Type'; 'No'; 'Name'; 'Product Name'; 'Model'; 'Bri-Col'; 'On'};
            app.LightsTable.RowName = {};
            app.LightsTable.ColumnSortable = [true true true true true true false true];
            app.LightsTable.ColumnEditable = [false false false false false false true false];
            app.LightsTable.CellEditCallback = createCallbackFcn(app, @LightsTableCellEdit, true);
            app.LightsTable.CellSelectionCallback = createCallbackFcn(app, @LightsTableCellSelection, true);
            app.LightsTable.SelectionChangedFcn = createCallbackFcn(app, @LightsTableSelectionChanged, true);
            app.LightsTable.Multiselect = 'off';
            app.LightsTable.Tooltip = {''};
            app.LightsTable.KeyPressFcn = createCallbackFcn(app, @LightsTableKeyPressed, true);
            app.RulesLabel_2 = uilabel(app.PhilipsHueDashboardv27UIFigure);
            app.RulesLabel_2.Tooltip = {'Columns can be rearranged by dragging the column heading.'};
            app.RulesLabel_2.Text = 'Rules';
            app.NumberofRulesNOTtriggeredsincethelastBridgefirmwareupdateLabel = uilabel(app.PhilipsHueDashboardv27UIFigure);
            app.NumberofRulesNOTtriggeredsincethelastBridgefirmwareupdateLabel.Text = 'Number of Rules NOT triggered since the last Bridge firmware update:';
            app.NeverTriggered = uieditfield(app.PhilipsHueDashboardv27UIFigure, 'numeric');
            app.NeverTriggered.Editable = 'off';
            app.NeverTriggered.Tooltip = {'The number of Rules ''never'' triggered is based on the ''timestriggered'' value held on the Bridge.  This is reset when the Bridge software is updated.  Also note that multiple Rules are created for every event or condition set for each device.'};
            app.RulesTable = uitable(app.PhilipsHueDashboardv27UIFigure);
            app.RulesTable.ColumnName = {'No'; 'Rule Name'; 'Created'; 'Last Trigger'; 'Trigs'; 'Status'; 'Action'; 'Target'; 'Tgt No'; 'Target Name'; 'Resource Link'};
            app.RulesTable.ColumnRearrangeable = 'on';
            app.RulesTable.RowName = {};
            app.RulesTable.ColumnSortable = true;
            app.inTimesTriggeredOrderLabel = uilabel(app.PhilipsHueDashboardv27UIFigure);
            app.inTimesTriggeredOrderLabel.Text = '(in Times Triggered Order)';
            app.Image = uiimage(app.PhilipsHueDashboardv27UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'huelogo.png');
            app.UnreachableLightsLabel = uilabel(app.PhilipsHueDashboardv27UIFigure);
            app.UnreachableLightsLabel.Tooltip = {'Any unreachable lights will be listed here.'};
            app.UnreachableLightsLabel.Text = 'Unreachable Lights';
            app.TableLights = uitable(app.PhilipsHueDashboardv27UIFigure);
            app.TableLights.ColumnName = {'ID'; 'Light'};
            app.TableLights.RowName = {};
            app.TableLights.ColumnSortable = true;
            app.DeviceswithBatteryLevelbelow40Label = uilabel(app.PhilipsHueDashboardv27UIFigure);
            app.DeviceswithBatteryLevelbelow40Label.Tooltip = {'Devices with low power will appear below.'};
            app.DeviceswithBatteryLevelbelow40Label.Text = 'Devices with Battery Level below 40%';
            app.TableSensors = uitable(app.PhilipsHueDashboardv27UIFigure);
            app.TableSensors.ColumnName = {'%'; 'Model'; 'Name'};
            app.TableSensors.RowName = {};
            app.TableSensors.ColumnSortable = true;
            app.DiagnosticsTextAreaLabel = uilabel(app.PhilipsHueDashboardv27UIFigure);
            app.DiagnosticsTextAreaLabel.Text = 'Diagnostics';
            app.DiagnosticsMsgTextArea = uitextarea(app.PhilipsHueDashboardv27UIFigure);
            app.DiagnosticsMsgTextArea.Editable = 'off';
            app.DiagnosticsTimeTextArea = uitextarea(app.PhilipsHueDashboardv27UIFigure);
            app.DiagnosticsTimeTextArea.Editable = 'off';
            app.BridgeConfigurationandUsagePanel = uipanel(app.PhilipsHueDashboardv27UIFigure);
            app.BridgeConfigurationandUsagePanel.Title = 'Bridge Configuration and Usage';
            app.InstalledLabel = uilabel(app.BridgeConfigurationandUsagePanel);
            app.InstalledLabel.Text = 'Installed';
            app.BridgeLimitLabel = uilabel(app.BridgeConfigurationandUsagePanel);
            app.BridgeLimitLabel.Text = 'Bridge Limit';
            app.UsedLabel = uilabel(app.BridgeConfigurationandUsagePanel);
            app.UsedLabel.Text = 'Used %';
            app.OnActiveLabel = uilabel(app.BridgeConfigurationandUsagePanel);
            app.OnActiveLabel.Text = 'On / Active';
            app.LightsLabel = uilabel(app.BridgeConfigurationandUsagePanel);
            app.LightsLabel.Text = 'Lights';
            app.AVLights = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.AVLights.Editable = 'off';
            app.BRLights = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.BRLights.Editable = 'off';
            app.PCLights = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.PCLights.ValueDisplayFormat = '%5.2f';
            app.PCLights.Editable = 'off';
            app.ONLights = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.ONLights.Editable = 'off';
            app.RulesLabel = uilabel(app.BridgeConfigurationandUsagePanel);
            app.RulesLabel.Text = 'Rules';
            app.AVRules = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.AVRules.Editable = 'off';
            app.BRRules = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.BRRules.Editable = 'off';
            app.PCRules = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.PCRules.ValueDisplayFormat = '%5.2f';
            app.PCRules.Editable = 'off';
            app.ONRules = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.ONRules.Editable = 'off';
            app.GroupsLabel = uilabel(app.BridgeConfigurationandUsagePanel);
            app.GroupsLabel.Text = 'Light Groups';
            app.AVGroups = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.AVGroups.Editable = 'off';
            app.BRGroups = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.BRGroups.Editable = 'off';
            app.PCGroups = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.PCGroups.ValueDisplayFormat = '%5.2f';
            app.PCGroups.Editable = 'off';
            app.ONGroups = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.ONGroups.Editable = 'off';
            app.ScenesLabel = uilabel(app.BridgeConfigurationandUsagePanel);
            app.ScenesLabel.Text = 'Scenes';
            app.AVScenes = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.AVScenes.Editable = 'off';
            app.BRScenes = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.BRScenes.Editable = 'off';
            app.PCScenes = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.PCScenes.ValueDisplayFormat = '%5.2f';
            app.PCScenes.Editable = 'off';
            app.SchedulesLabel = uilabel(app.BridgeConfigurationandUsagePanel);
            app.SchedulesLabel.Text = 'Schedules';
            app.AVSchedules = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.AVSchedules.Editable = 'off';
            app.BRSchedules = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.BRSchedules.Editable = 'off';
            app.PCSchedules = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.PCSchedules.ValueDisplayFormat = '%5.2f';
            app.PCSchedules.Editable = 'off';
            app.ONSchedules = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.ONSchedules.Editable = 'off';
            app.ResourceLinksLabel = uilabel(app.BridgeConfigurationandUsagePanel);
            app.ResourceLinksLabel.Text = 'Resource Links';
            app.AVResourceLinks = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.AVResourceLinks.Editable = 'off';
            app.BRResourceLinks = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.BRResourceLinks.Editable = 'off';
            app.PCResourceLinks = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.PCResourceLinks.ValueDisplayFormat = '%5.2f';
            app.PCResourceLinks.Editable = 'off';
            app.SensorsLabel = uilabel(app.BridgeConfigurationandUsagePanel);
            app.SensorsLabel.Text = 'Sensors';
            app.AVSensors = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.AVSensors.Editable = 'off';
            app.BRSensors = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.BRSensors.Editable = 'off';
            app.PCSensors = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.PCSensors.ValueDisplayFormat = '%5.2f';
            app.PCSensors.Editable = 'off';
            app.MotionSensors = uilabel(app.BridgeConfigurationandUsagePanel);
            app.MotionSensors.Text = '99';
            app.MotionSensorsLabel = uilabel(app.BridgeConfigurationandUsagePanel);
            app.MotionSensorsLabel.Text = 'Motion Sensors ';
            app.DimmerSwitches = uilabel(app.BridgeConfigurationandUsagePanel);
            app.DimmerSwitches.Text = '99';
            app.SwitchesLabel = uilabel(app.BridgeConfigurationandUsagePanel);
            app.SwitchesLabel.Text = 'Switches';
            app.devicesLabel = uilabel(app.BridgeConfigurationandUsagePanel);
            app.devicesLabel.Text = 'devices:';
            app.TotalDevices = uilabel(app.BridgeConfigurationandUsagePanel);
            app.TotalDevices.Text = '88';
            app.InLabel = uilabel(app.BridgeConfigurationandUsagePanel);
            app.InLabel.Text = 'In';
            app.LabelLightsOn = uilabel(app.PhilipsHueDashboardv27UIFigure);
            app.LabelLightsOn.Text = '23';
            app.onLabel = uilabel(app.PhilipsHueDashboardv27UIFigure);
            app.onLabel.Text = 'on';
            app.LastMotionDetected = uilabel(app.PhilipsHueDashboardv27UIFigure);
            app.LastMotionDetected.Tooltip = {'The most recent motion detected across all available motion sensors.'};
            app.LastMotionDetected.Text = 'No Motion detected.';
            app.WarningsPanel = uipanel(app.PhilipsHueDashboardv27UIFigure);
            app.WarningsPanel.Title = 'Warnings';
            app.UnreachableLightsLabel_2 = uilabel(app.WarningsPanel);
            app.UnreachableLightsLabel_2.Tooltip = {'Number of unreachable Lights.  Often temporary.'};
            app.UnreachableLightsLabel_2.Text = 'Unreachable Lights';
            app.URLights = uieditfield(app.WarningsPanel, 'numeric');
            app.URLights.Editable = 'off';
            app.URLights.Tooltip = {'Number of unreachable Lights.  Often temporary.'};
            app.LowBatteryDevicesLabel = uilabel(app.WarningsPanel);
            app.LowBatteryDevicesLabel.Tooltip = {'Number of devices with a battery level below 40%.'};
            app.LowBatteryDevicesLabel.Text = 'Low Battery Devices';
            app.LBSensors = uieditfield(app.WarningsPanel, 'numeric');
            app.LBSensors.Editable = 'off';
            app.LBSensors.Tooltip = {'Number of devices with a battery level below 40%.'};
            app.ContextMenu = uicontextmenu(app.PhilipsHueDashboardv27UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            % Show the figure after all components are created
            app.PhilipsHueDashboardv27UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HueDashboard

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PhilipsHueDashboardv27UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PhilipsHueDashboardv27UIFigure)
        end
    end
end